classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnalyzerMenu                    matlab.ui.container.Menu
        ManualMarkeroptionsMenu         matlab.ui.container.Menu
        ArcusMotorMenu                  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        currentpositionEditField        matlab.ui.control.NumericEditField
        currentpositionEditFieldLabel   matlab.ui.control.Label
        currentpositionButton           matlab.ui.control.Button
        autorotationButton              matlab.ui.control.Button
        StepPauseEditField              matlab.ui.control.NumericEditField
        StepPauseEditFieldLabel         matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        TimesEditField                  matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        EditField_13                    matlab.ui.control.NumericEditField
        SetMoveModeButton               matlab.ui.control.Button
        EditField4_2                    matlab.ui.control.NumericEditField
        EditField3_3                    matlab.ui.control.NumericEditField
        EditField2_3                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        PosH_velL_velAccnLabel          matlab.ui.control.Label
        SettheParametersButton          matlab.ui.control.Button
        SetcurrentPositionto0Button     matlab.ui.control.Button
        ResetParametersButton           matlab.ui.control.Button
        posh_vell_velaccnLabel          matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        GettheParametersButton          matlab.ui.control.Button
        AbortOperationButton            matlab.ui.control.Button
        deleteLabel                     matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        DeleteLabel                     matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        SaveLabel                       matlab.ui.control.Label
        ImportExcelFileLabel            matlab.ui.control.Label
        ImportdatatotheTableLabel       matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        NextPeakLeftButton              matlab.ui.control.Button
        NextPeakRightButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AgilentEXA_N9010ASignalAnalyzerLabel  matlab.ui.control.Label
        AgilentMXG_N5182ASignalGeneratorLabel  matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        MotorStepSizeinButton           matlab.ui.control.Button
        MotorStepSizeSlider             matlab.ui.control.Slider
        MotorStepSizeSliderLabel        matlab.ui.control.Label
        dBmLabel                        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        MotorInformationButton          matlab.ui.control.Button
        Lamp_5                          matlab.ui.control.Lamp
        ArcusMotorButton                matlab.ui.control.StateButton
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        GettheFrequencyforMarkerButton  matlab.ui.control.Button
        EditField_5                     matlab.ui.control.NumericEditField
        EditField2_2                    matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        PowerButton                     matlab.ui.control.Button
        FrequencyButton                 matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        GeneratorButton                 matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        GenRFopONOFFButton              matlab.ui.control.StateButton
        EditField_4                     matlab.ui.control.NumericEditField
        GetthePowerforMarkerButton      matlab.ui.control.Button
        ManualMarkerOptionsCheckBox     matlab.ui.control.CheckBox
        EditField5                      matlab.ui.control.NumericEditField
        EditField4                      matlab.ui.control.NumericEditField
        EditField3_2                    matlab.ui.control.NumericEditField
        SpanButton                      matlab.ui.control.Button
        BWButton                        matlab.ui.control.Button
        CenterFreqButton                matlab.ui.control.Button
        EditField3                      matlab.ui.control.NumericEditField
        EditField2                      matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Lamp_4                          matlab.ui.control.Lamp
        ConstantPeakSearchMarkerButton  matlab.ui.control.StateButton
        PutaMarkerButton                matlab.ui.control.Button
        MarkerOFFButton                 matlab.ui.control.Button
        NextPeakButton                  matlab.ui.control.Button
        ModePresetButton                matlab.ui.control.Button
        AutoTuneButton                  matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        AllMarkersOFFButton             matlab.ui.control.Button
        PeakSearchButton                matlab.ui.control.Button
        AnalyzerButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        obj1;
        obj;
        T;
        file_name;
    end
    
    methods (Access = public)
        
        function auto_process(~, times, degrees, seconds)
            app.obj = Analyzer_EXA_N9010A();
            H = table([],[],[],'VariableNames', ["Step_Size", "Power", "Freq"]);
            app.UITable = [];
            for step_times = 1:times 
                Step_Size = step_times * degrees;
                MyArcus.PositionTo(Step_Size);
                Power = str2double(app.obj.Get_Marker_Power(1));
                Freq  = str2double(app.obj.Get_Marker_Freq(1));
                H(end+1,:) = {Step_Size, Power, Freq}; % Add new rows of data in the loop
                pause(seconds);
            end
            pause(2);
            MyArcus.PositionTo(0);
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
        end

        % Value changed function: GenRFopONOFFButton
        function GenRFopONOFFButtonValueChanged(app, event)
            value = app.GenRFopONOFFButton.Value;
            if (value==1)
                app.obj1.RF_OutPut_ON();
                app.Lamp.Color='green';
            else
                app.obj1.RF_OutPut_OFF();
                app.Lamp.Color='red';
            end
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.Disconnect_Analyzer();
                app.Lamp_3.Color='red';
            end
        end

        % Button pushed function: PeakSearchButton
        function PeakSearchButtonPushed(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: AllMarkersOFFButton
        function AllMarkersOFFButtonPushed(app, event)
            app.obj.Turn_off_All_Markers();
        end

        % Button pushed function: AutoTuneButton
        function AutoTuneButtonPushed(app, event)
            app.obj.Auto_Tune();
        end

        % Button pushed function: ModePresetButton
        function ModePresetButtonPushed(app, event)
            app.obj.Mode_Preset();
        end

        % Button pushed function: NextPeakButton
        function NextPeakButtonPushed(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Button pushed function: MarkerOFFButton
        function MarkerOFFButtonPushed(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Button pushed function: PutaMarkerButton
        function PutaMarkerButtonPushed(app, event)
            app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: ConstantPeakSearchMarkerButton
        function ConstantPeakSearchMarkerButtonValueChanged(app, event)
            value = app.ConstantPeakSearchMarkerButton.Value;
            if (value==1)
                app.obj.Set_Marker_onPeak(1);
                app.Lamp_4.Color='green';
            else
                app.obj.Turn_off_Marker(1);
                app.Lamp_4.Color='red';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
          app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.obj1.SetFrequeny(app.EditField_3.Value);
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            app.obj1.SetFrequeny(app.EditField_3.Value);
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            app.obj1.SetPowerLevel(app.EditField2_2.Value);
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            app.obj1.SetPowerLevel(app.EditField2_2.Value);
        end

        % Value changed function: EditField3_2
        function EditField3_2ValueChanged(app, event)
            app.obj.Set_Center_Frequency(app.EditField3_2.Value);
        end

        % Button pushed function: CenterFreqButton
        function CenterFreqButtonPushed(app, event)
            app.obj.Set_Center_Frequency(app.EditField3_2.Value);
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            app.obj.Set_BnadWidth(app.EditField4.Value);
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            app.obj.Set_BnadWidth(app.EditField4.Value);
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            app.obj.Set_Span(app.EditField5.Value);
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            app.obj.Set_Span(app.EditField5.Value);
        end

        % Value changed function: ManualMarkerOptionsCheckBox
        function ManualMarkerOptionsCheckBoxValueChanged(app, event)
            value = app.ManualMarkerOptionsCheckBox.Value;
            switch value
                case 0
                    app.PutaMarkerButton.Visible        = 'OFF';
                    app.MarkerOFFButton.Visible     = 'OFF';
                    app.AllMarkersOFFButton.Visible = 'OFF';
                    app.NextPeakButton.Visible      = 'OFF';
                    app.PeakSearchButton.Visible    = 'OFF';
                    app.NextPeakLeftButton.Visible  = 'OFF';
                    app.NextPeakRightButton.Visible = 'OFF';
                    app.EditField.Visible   = 'OFF';
                    app.EditField_2.Visible = 'OFF';
                    app.EditField3.Visible  = 'OFF';
                    app.EditField2.Visible  = 'OFF';
                    app.EditField_10.Visible= 'OFF';
                    app.EditField_9.Visible = 'OFF';
                case 1
                    app.PutaMarkerButton.Visible        = 'ON';
                    app.MarkerOFFButton.Visible     = 'ON';
                    app.AllMarkersOFFButton.Visible = 'ON';
                    app.NextPeakButton.Visible      = 'ON';
                    app.PeakSearchButton.Visible    = 'ON';
                    app.NextPeakLeftButton.Visible  = 'ON';
                    app.NextPeakRightButton.Visible = 'ON';
                    app.EditField.Visible   = 'ON';
                    app.EditField_2.Visible = 'ON';
                    app.EditField3.Visible  = 'ON';
                    app.EditField2.Visible  = 'ON';
                    app.EditField_10.Visible= 'ON';
                    app.EditField_9.Visible = 'ON';
            end
        end

        % Button pushed function: GetthePowerforMarkerButton
        function GetthePowerforMarkerButtonPushed(app, event)
           app.EditField_4.Value = str2double(app.obj.Get_Marker_Power(app.EditField_5.Value));           
        end

        % Button pushed function: GettheFrequencyforMarkerButton
        function GettheFrequencyforMarkerButtonPushed(app, event)
            app.EditField_7.Value = str2double(app.obj.Get_Marker_Freq(app.EditField_6.Value));
        end

        % Value changed function: ArcusMotorButton
        function ArcusMotorButtonValueChanged(app, event)
            value = app.ArcusMotorButton.Value;
            if (value==1)
                run ("MyArcus.m");
                MyArcus.Enable();
                app.Lamp_5.Color='green';
            else
                MyArcus.Disable();
                app.Lamp_5.Color='red';
            end
        end

        % Button pushed function: MotorInformationButton
        function MotorInformationButtonPushed(app, event)
            app.TextArea.Value = struct2array(MyArcus.DevInfo);
        end

        % Value changed function: MotorStepSizeSlider
        function MotorStepSizeSliderValueChanged(app, event)
            app.EditField_8.Value = app.MotorStepSizeSlider.Value;
            MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Button pushed function: MotorStepSizeinButton
        function MotorStepSizeinButtonPushed(app, event)
              MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.MotorStepSizeSlider.Value = app.EditField_8.Value;
            MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Button pushed function: NextPeakLeftButton
        function NextPeakLeftButtonPushed(app, event)
          app.obj.Next_Peake_Left(app.EditField_10.Value);
        end

        % Button pushed function: NextPeakRightButton
        function NextPeakRightButtonPushed(app, event)
            app.obj.Next_Peake_Right(app.EditField_9.Value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Motor_Step_Size = app.EditField_8.Value;
            Marker_Frequency= app.EditField_7.Value;
            Marker_Power    = app.EditField_4.Value;
            new_row = {Motor_Step_Size Marker_Frequency Marker_Power};
            app.UITable.Data = [app.T; new_row];
            app.T = app.UITable.Data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile;
            app.file_name = [path file];
            app.T = readtable(app.file_name);
            app.UITable.Data = app.T;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            writetable(app.T,app.file_name);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.UITable.Data(end,:) = [];
            app.T(end,:) = [];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            MyArcus.Stop();
        end

        % Button pushed function: AbortOperationButton
        function AbortOperationButtonPushed(app, event)
            MyArcus.Abort();
        end

        % Button pushed function: GettheParametersButton
        function GettheParametersButtonPushed(app, event)
            C = struct2cell(MyArcus.getParams);
            H = cell2mat(C);
            app.TextArea_2.Value = mat2str(H);
        end

        % Button pushed function: ResetParametersButton
        function ResetParametersButtonPushed(app, event)
            MyArcus.resetStage();
        end

        % Button pushed function: SetcurrentPositionto0Button
        function SetcurrentPositionto0ButtonPushed(app, event)
            MyArcus.BackToZero();
        end

        % Button pushed function: SettheParametersButton
        function SettheParametersButtonPushed(app, event)
            C = app.EditField_12.Value; 
            H = app.EditField2_3.Value;
            I = app.EditField3_3.Value; 
            R = app.EditField4_2.Value;
           MyArcus.setParams(C,H,I,R);
        end

        % Button pushed function: SetMoveModeButton
        function SetMoveModeButtonPushed(app, event)
            MyArcus.setMoveMode(app.EditField_13.Value);
                % set move mode to INC = (1) (incremental) or ABS = (0) (abbsolute)
                % 0 is recommended for default use
        end

        % Button pushed function: autorotationButton
        function autorotationButtonPushed(app, event)
            times = app.TimesEditField.Value;
            degrees = app.StepSizeEditField.Value;
            seconds = app.StepPauseEditField.Value;
            app.auto_process(times, degrees, seconds);
        end

        % Button pushed function: currentpositionButton
        function currentpositionButtonPushed(app, event)
            app.currentpositionEditField.Value = MyArcus.Current_Pos;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalyzerMenu = uimenu(app.UIFigure);
            app.AnalyzerMenu.Text = 'Analyzer';
            app.ManualMarkeroptionsMenu = uimenu(app.AnalyzerMenu);
            app.ManualMarkeroptionsMenu.Text = 'Manual Marker options';
            app.ArcusMotorMenu = uimenu(app.UIFigure);
            app.ArcusMotorMenu.Text = 'Arcus Motor';
            app.Menu = uimenu(app.ArcusMotorMenu);
            app.Menu.Text = 'Menu';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer  ';
            app.PeakSearchButton = uibutton(app.UIFigure, 'push');
            app.PeakSearchButton.ButtonPushedFcn = createCallbackFcn(app, @PeakSearchButtonPushed, true);
            app.PeakSearchButton.Visible = 'off';
            app.PeakSearchButton.Text = '  Peak Search';
            app.AllMarkersOFFButton = uibutton(app.UIFigure, 'push');
            app.AllMarkersOFFButton.ButtonPushedFcn = createCallbackFcn(app, @AllMarkersOFFButtonPushed, true);
            app.AllMarkersOFFButton.Visible = 'off';
            app.AllMarkersOFFButton.Text = 'All Markers OFF';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.AutoTuneButton = uibutton(app.UIFigure, 'push');
            app.AutoTuneButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuneButtonPushed, true);
            app.AutoTuneButton.Text = 'Auto Tune';
            app.ModePresetButton = uibutton(app.UIFigure, 'push');
            app.ModePresetButton.ButtonPushedFcn = createCallbackFcn(app, @ModePresetButtonPushed, true);
            app.ModePresetButton.Text = 'Mode Preset';
            app.NextPeakButton = uibutton(app.UIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakButtonPushed, true);
            app.NextPeakButton.Visible = 'off';
            app.NextPeakButton.Text = '  Next Peak';
            app.MarkerOFFButton = uibutton(app.UIFigure, 'push');
            app.MarkerOFFButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerOFFButtonPushed, true);
            app.MarkerOFFButton.Visible = 'off';
            app.MarkerOFFButton.Text = '  Marker OFF';
            app.PutaMarkerButton = uibutton(app.UIFigure, 'push');
            app.PutaMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @PutaMarkerButtonPushed, true);
            app.PutaMarkerButton.Visible = 'off';
            app.PutaMarkerButton.Text = 'Put a Marker';
            app.ConstantPeakSearchMarkerButton = uibutton(app.UIFigure, 'state');
            app.ConstantPeakSearchMarkerButton.ValueChangedFcn = createCallbackFcn(app, @ConstantPeakSearchMarkerButtonValueChanged, true);
            app.ConstantPeakSearchMarkerButton.Text = {' Constant Peak'; 'Search Marker'};
            app.Lamp_4 = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 5];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.EditField_2.Value = 1;
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Limits = [1 5];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Visible = 'off';
            app.EditField2.Value = 1;
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Limits = [1 5];
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.EditField3.Visible = 'off';
            app.EditField3.Value = 1;
            app.CenterFreqButton = uibutton(app.UIFigure, 'push');
            app.CenterFreqButton.ButtonPushedFcn = createCallbackFcn(app, @CenterFreqButtonPushed, true);
            app.CenterFreqButton.Text = {'  Center '; '   Freq.'};
            app.BWButton = uibutton(app.UIFigure, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Text = '  BW';
            app.SpanButton = uibutton(app.UIFigure, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = '  Span';
            app.EditField3_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_2.Limits = [9000 7000000000];
            app.EditField3_2.ValueChangedFcn = createCallbackFcn(app, @EditField3_2ValueChanged, true);
            app.EditField3_2.Value = 3500000000;
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.EditField4.Value = 3000000;
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.Limits = [0 6900000000];
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.EditField5.Value = 6500000000;
            app.ManualMarkerOptionsCheckBox = uicheckbox(app.UIFigure);
            app.ManualMarkerOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualMarkerOptionsCheckBoxValueChanged, true);
            app.ManualMarkerOptionsCheckBox.Text = {'  Manual Marker'; '      Options'};
            app.GetthePowerforMarkerButton = uibutton(app.UIFigure, 'push');
            app.GetthePowerforMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @GetthePowerforMarkerButtonPushed, true);
            app.GetthePowerforMarkerButton.Text = {'  Get the Power '; '     for Marker'};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.GenRFopONOFFButton = uibutton(app.UIFigure, 'state');
            app.GenRFopONOFFButton.ValueChangedFcn = createCallbackFcn(app, @GenRFopONOFFButtonValueChanged, true);
            app.GenRFopONOFFButton.Tag = 'state button on and off';
            app.GenRFopONOFFButton.Text = {'Gen. RF    '; 'o/p ON/OFF'};
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.FrequencyButton = uibutton(app.UIFigure, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.PowerButton = uibutton(app.UIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = '  Power';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [100000 1000000000];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1000000000;
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.Limits = [-110 17];
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.EditField2_2.Value = 10;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 1;
            app.GettheFrequencyforMarkerButton = uibutton(app.UIFigure, 'push');
            app.GettheFrequencyforMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @GettheFrequencyforMarkerButtonPushed, true);
            app.GettheFrequencyforMarkerButton.Text = {'Get the Frequency'; '      for Marker'};
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Editable = 'off';
            app.ArcusMotorButton = uibutton(app.UIFigure, 'state');
            app.ArcusMotorButton.ValueChangedFcn = createCallbackFcn(app, @ArcusMotorButtonValueChanged, true);
            app.ArcusMotorButton.Text = {'Arcus    '; 'Motor    '};
            app.Lamp_5 = uilamp(app.UIFigure);
            app.MotorInformationButton = uibutton(app.UIFigure, 'push');
            app.MotorInformationButton.ButtonPushedFcn = createCallbackFcn(app, @MotorInformationButtonPushed, true);
            app.MotorInformationButton.Text = {' Motor'; 'Information'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.dBmLabel = uilabel(app.UIFigure);
            app.dBmLabel.Text = 'dBm';
            app.MotorStepSizeSliderLabel = uilabel(app.UIFigure);
            app.MotorStepSizeSliderLabel.Text = 'Motor Step Size';
            app.MotorStepSizeSlider = uislider(app.UIFigure);
            app.MotorStepSizeSlider.Limits = [0 360];
            app.MotorStepSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MotorStepSizeSliderValueChanged, true);
            app.MotorStepSizeinButton = uibutton(app.UIFigure, 'push');
            app.MotorStepSizeinButton.ButtonPushedFcn = createCallbackFcn(app, @MotorStepSizeinButtonPushed, true);
            app.MotorStepSizeinButton.Text = {'Motor Step'; 'Size in ( °)'};
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.AgilentMXG_N5182ASignalGeneratorLabel = uilabel(app.UIFigure);
            app.AgilentMXG_N5182ASignalGeneratorLabel.Text = 'Agilent MXG_N5182A Signal Generator ...............................................................................................................................';
            app.AgilentEXA_N9010ASignalAnalyzerLabel = uilabel(app.UIFigure);
            app.AgilentEXA_N9010ASignalAnalyzerLabel.Text = 'Agilent EXA_N9010A Signal Analyzer...................................................................................................................................';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Step_Size'; 'Power'; 'Freq'};
            app.UITable.RowName = {};
            app.NextPeakRightButton = uibutton(app.UIFigure, 'push');
            app.NextPeakRightButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakRightButtonPushed, true);
            app.NextPeakRightButton.Visible = 'off';
            app.NextPeakRightButton.Text = {'Next Peak'; '   Right'};
            app.NextPeakLeftButton = uibutton(app.UIFigure, 'push');
            app.NextPeakLeftButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakLeftButtonPushed, true);
            app.NextPeakLeftButton.Visible = 'off';
            app.NextPeakLeftButton.Text = {'Next Peak'; '     Left'};
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 1;
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Visible = 'off';
            app.EditField_10.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-import-48.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icons8-microsoft-excel-2019-48.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'icons8-save-close-48.png';
            app.Button_3.Text = '';
            app.ImportdatatotheTableLabel = uilabel(app.UIFigure);
            app.ImportdatatotheTableLabel.Text = {'Import data'; 'to the Table'};
            app.ImportExcelFileLabel = uilabel(app.UIFigure);
            app.ImportExcelFileLabel.Text = {'Import '; 'Excel File'};
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = 'Save ';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'icons8-delete-key-100.png';
            app.Button_4.Text = '';
            app.DeleteLabel = uilabel(app.UIFigure);
            app.DeleteLabel.Text = 'Delete';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.deleteLabel = uilabel(app.UIFigure);
            app.deleteLabel.Text = 'delete';
            app.AbortOperationButton = uibutton(app.UIFigure, 'push');
            app.AbortOperationButton.ButtonPushedFcn = createCallbackFcn(app, @AbortOperationButtonPushed, true);
            app.AbortOperationButton.Text = 'Abort Operation';
            app.GettheParametersButton = uibutton(app.UIFigure, 'push');
            app.GettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GettheParametersButtonPushed, true);
            app.GettheParametersButton.Text = {'Get the'; 'Parameters'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.posh_vell_velaccnLabel = uilabel(app.UIFigure);
            app.posh_vell_velaccnLabel.Text = '[pos; h_vel; l_vel; accn]';
            app.ResetParametersButton = uibutton(app.UIFigure, 'push');
            app.ResetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParametersButtonPushed, true);
            app.ResetParametersButton.Text = {'Reset'; 'Parameters'};
            app.SetcurrentPositionto0Button = uibutton(app.UIFigure, 'push');
            app.SetcurrentPositionto0Button.ButtonPushedFcn = createCallbackFcn(app, @SetcurrentPositionto0ButtonPushed, true);
            app.SetcurrentPositionto0Button.Text = {'Set current'; 'Position to 0°'};
            app.SettheParametersButton = uibutton(app.UIFigure, 'push');
            app.SettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SettheParametersButtonPushed, true);
            app.SettheParametersButton.Text = {'Set the'; 'Parameters'};
            app.PosH_velL_velAccnLabel = uilabel(app.UIFigure);
            app.PosH_velL_velAccnLabel.Text = 'Pos ( °); H_vel;   L_vel;  Accn';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Limits = [0 360];
            app.EditField2_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3.Limits = [100 20000];
            app.EditField2_3.Value = 1000;
            app.EditField3_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_3.Limits = [100 20000];
            app.EditField3_3.Value = 100;
            app.EditField4_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4_2.Limits = [10 1000];
            app.EditField4_2.Value = 300;
            app.SetMoveModeButton = uibutton(app.UIFigure, 'push');
            app.SetMoveModeButton.ButtonPushedFcn = createCallbackFcn(app, @SetMoveModeButtonPushed, true);
            app.SetMoveModeButton.Text = 'Set Move Mode';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Limits = [0 1];
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepPauseEditFieldLabel = uilabel(app.UIFigure);
            app.StepPauseEditFieldLabel.Text = 'Step Pause';
            app.StepPauseEditField = uieditfield(app.UIFigure, 'numeric');
            app.autorotationButton = uibutton(app.UIFigure, 'push');
            app.autorotationButton.ButtonPushedFcn = createCallbackFcn(app, @autorotationButtonPushed, true);
            app.autorotationButton.Text = 'auto rotation';
            app.currentpositionButton = uibutton(app.UIFigure, 'push');
            app.currentpositionButton.ButtonPushedFcn = createCallbackFcn(app, @currentpositionButtonPushed, true);
            app.currentpositionButton.Text = 'current position';
            app.currentpositionEditFieldLabel = uilabel(app.UIFigure);
            app.currentpositionEditFieldLabel.Text = 'current position';
            app.currentpositionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end